classdef Pedal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MixKnobLabel      matlab.ui.control.Label
        MixKnob           matlab.ui.control.Knob
        WetnessKnobLabel  matlab.ui.control.Label
        WetnessKnob       matlab.ui.control.Knob
        DecayKnobLabel    matlab.ui.control.Label
        DecayKnob         matlab.ui.control.Knob
        Switch            matlab.ui.control.ToggleSwitch
        PauseButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value
            if strcmpi('On', value)
            % fileReader = dsp.AudioFileReader('gitarr.wav','SamplesPerFrame',1024);
            %deviceWriter = audioDeviceWriter('SampleRate', fileReader.SampleRate);
            persistent player
            
            [y,Fs] = audioread('gitarr.wav')
            player = audioplayer(y,Fs);
            %while ~isDone(fileReader)
            play(player,[1 (get(player, 'SampleRate')*3)]);
                if strcmpi('Off', value)
                    pause(player);
                end
              %  audio = fileReader();
               % deviceWriter(audio);
               
           % end
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if strcmpi( 'Pause' ,app.PauseButton.Text)
                app.Switch.Value = 'Off'
                app.PauseButton.Text = 'Play'
            else app.PauseButton.Text = 'Pause'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MixKnobLabel = uilabel(app.UIFigure);
            app.MixKnobLabel.Text = 'Mix';
            app.MixKnob = uiknob(app.UIFigure, 'continuous');
            app.WetnessKnobLabel = uilabel(app.UIFigure);
            app.WetnessKnobLabel.Text = 'Wetness';
            app.WetnessKnob = uiknob(app.UIFigure, 'continuous');
            app.DecayKnobLabel = uilabel(app.UIFigure);
            app.DecayKnobLabel.Text = 'Decay';
            app.DecayKnob = uiknob(app.UIFigure, 'continuous');
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pedal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end